classdef ICPD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISEAcellpackdatabaseUIFigure   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CARL_ISEA_Logo_2               matlab.ui.control.Image
        CARL_ISEA_Logo                 matlab.ui.control.Image
        CellBuilding                   matlab.ui.container.Panel
        GridLayoutCellBuilding         matlab.ui.container.GridLayout
        TargetCapacityMainLabel        matlab.ui.control.Label
        TargetCapacityMainField        matlab.ui.control.NumericEditField
        EditFieldDatabasePath          matlab.ui.control.EditField
        LoadDatabaseButton             matlab.ui.control.Button
        OwnfigureCheckBox              matlab.ui.control.CheckBox
        BusyLampLabel                  matlab.ui.control.Label
        BusyMainLamp                   matlab.ui.control.Lamp
        NameofthenewcellLabel          matlab.ui.control.Label
        CellNameField                  matlab.ui.control.EditField
        ElectrodestackLabel            matlab.ui.control.Label
        ElectrodeStackMainDropDown     matlab.ui.control.DropDown
        HousingLabel                   matlab.ui.control.Label
        HousingMainDropDown            matlab.ui.control.DropDown
        CreationModeLabel              matlab.ui.control.Label
        CreationModeButtonMainGroup    matlab.ui.container.ButtonGroup
        fitCapacityMainButton          matlab.ui.control.RadioButton
        fitHousingMainButton           matlab.ui.control.RadioButton
        standardMainButton             matlab.ui.control.RadioButton
        SourceofthedataLabel           matlab.ui.control.Label
        sourceField                    matlab.ui.control.EditField
        IssourceconfidentialLabel      matlab.ui.control.Label
        DropDownConfidential           matlab.ui.control.DropDown
        PlotCellButton                 matlab.ui.control.Button
        CalcpropertiesButton           matlab.ui.control.Button
        SavecellButton                 matlab.ui.control.Button
        DynamicCellInformationPanel    matlab.ui.container.Panel
        InformationCellGridLayout      matlab.ui.container.GridLayout
        SaveFigureCellButton           matlab.ui.control.Button
        SwitchOCVMainCell              matlab.ui.control.Switch
        NomChaVoltageLabel             matlab.ui.control.Label
        NomDisVoltageLabel             matlab.ui.control.Label
        VolPowerDensityLabel           matlab.ui.control.Label
        GravPowerDensityLabel          matlab.ui.control.Label
        VolEnergyDensityLabel          matlab.ui.control.Label
        GravEnergyDensityLabel         matlab.ui.control.Label
        InternalResistanceLabel        matlab.ui.control.Label
        TypeCell                       matlab.ui.control.Label
        WeightLabel                    matlab.ui.control.Label
        VolumeLabel                    matlab.ui.control.Label
        PowerLabel                     matlab.ui.control.Label
        CapacityLabel                  matlab.ui.control.Label
        HousingMainUIAxes              matlab.ui.control.UIAxes
        OCVMainUIAxes                  matlab.ui.control.UIAxes
        AnalysisPanel                  matlab.ui.container.Panel
        AnalysisLayout                 matlab.ui.container.GridLayout
        TabGroupMainPanel              matlab.ui.container.TabGroup
        ComparisonofdifferentcellsTab  matlab.ui.container.Tab
        GridLayoutComparison           matlab.ui.container.GridLayout
        RagoneDiagramButton            matlab.ui.control.Button
        ComparisonTable                matlab.ui.control.Table
        SwitchOCVMain                  matlab.ui.control.Switch
        SaveFigureCompareButton        matlab.ui.control.Button
        OwnfigureCompareCheckBox       matlab.ui.control.CheckBox
        ComparecellsButton             matlab.ui.control.Button
        ComparisonSpiderUIAxes         matlab.ui.control.UIAxes
        ComparisonOCVUIAxes            matlab.ui.control.UIAxes
        ComparisonHousingsUIAxes       matlab.ui.control.UIAxes
        ParametervariationTab          matlab.ui.container.Tab
        ParaVariationGridLayout        matlab.ui.container.GridLayout
        ParaVariSurfaceCapacityField   matlab.ui.control.NumericEditField
        HoldSurfaceCapacityCheckBox    matlab.ui.control.CheckBox
        FitStackHousingCheckBox        matlab.ui.control.CheckBox
        SaveVariationsButton           matlab.ui.control.Button
        ShowvariationsButton           matlab.ui.control.StateButton
        PlotvariationsButton           matlab.ui.control.Button
        NVariationsLabel               matlab.ui.control.Label
        VaryParaListBox                matlab.ui.control.ListBox
        ChooseparameterorcomponentstovaryListBoxLabel  matlab.ui.control.Label
        PowerParaVarLabel              matlab.ui.control.Label
        CapacityParaVarLabel           matlab.ui.control.Label
        ComponentvariationPanel        matlab.ui.container.Panel
        ComponentVariationGridLayout   matlab.ui.container.GridLayout
        ActivematerialPanel            matlab.ui.container.Panel
        ACMTableGridLayout             matlab.ui.container.GridLayout
        ActiveMaterialParaVarTable     matlab.ui.control.Table
        HousingPanel                   matlab.ui.container.Panel
        HousingTableGridLayout         matlab.ui.container.GridLayout
        HousingParaVarTable            matlab.ui.control.Table
        ParametervariationPanel        matlab.ui.container.Panel
        ParaVarTableGridLayout         matlab.ui.container.GridLayout
        VariedCellsTable               matlab.ui.control.Table
        ParaVarTable                   matlab.ui.control.Table
        CellParaVarDropDown            matlab.ui.control.DropDown
        SelectbasecellLabel            matlab.ui.control.Label
        InterfacetoPCMTab              matlab.ui.container.Tab
        GridLayoutCellExport           matlab.ui.container.GridLayout
        ExportbatteryIDasPDFCheckBox   matlab.ui.control.CheckBox
        ExporttobatteryIDButton        matlab.ui.control.Button
        SimulationOptionsPanel         matlab.ui.container.Panel
        SimulationOptionsGridLayout    matlab.ui.container.GridLayout
        DivisionField                  matlab.ui.control.NumericEditField
        ParticleDivisionField          matlab.ui.control.NumericEditField
        ParticleTypeDropDown           matlab.ui.control.DropDown
        SOCField                       matlab.ui.control.NumericEditField
        CellTempField                  matlab.ui.control.NumericEditField
        AmbientTempField               matlab.ui.control.NumericEditField
        DivisionLabel                  matlab.ui.control.Label
        ParticledivisonLabel           matlab.ui.control.Label
        ParticletypeLabel              matlab.ui.control.Label
        StateofchargeLabel             matlab.ui.control.Label
        CelltemperatureLabel           matlab.ui.control.Label
        AmbienttemperatureLabel        matlab.ui.control.Label
        FittingOptionsPanel            matlab.ui.container.Panel
        FittingOptionsGridLayout       matlab.ui.container.GridLayout
        VerificationSampleRateField    matlab.ui.control.NumericEditField
        SamplerateLabel                matlab.ui.control.Label
        UseVerificationButtonGroup     matlab.ui.container.ButtonGroup
        VerificationNoButton           matlab.ui.control.RadioButton
        VerficationYesButton           matlab.ui.control.RadioButton
        TDFDataDropDown                matlab.ui.control.DropDown
        TDFECDElementsField            matlab.ui.control.EditField
        UseTDFFittingButtonGroup       matlab.ui.container.ButtonGroup
        EISTDFNoButton                 matlab.ui.control.RadioButton
        EISTDFYesButton                matlab.ui.control.RadioButton
        EISStartTemperaturField        matlab.ui.control.NumericEditField
        EISStartSOCField               matlab.ui.control.NumericEditField
        UseEISDRTButtonGroup           matlab.ui.container.ButtonGroup
        EISDRTNoButton                 matlab.ui.control.RadioButton
        EISDRTYesButton                matlab.ui.control.RadioButton
        EISMadTolceranceField          matlab.ui.control.NumericEditField
        UseEISPostProcessingButtonGroup  matlab.ui.container.ButtonGroup
        EISPostProcessingNoButton      matlab.ui.control.RadioButton
        EISPostProcessingYesButton     matlab.ui.control.RadioButton
        EISModellDropDown              matlab.ui.control.DropDown
        UseEISFittingButtonGroup       matlab.ui.container.ButtonGroup
        EISFittingNoButton             matlab.ui.control.RadioButton
        EISFittingYesButton            matlab.ui.control.RadioButton
        VerificationLabel              matlab.ui.control.Label
        TDFDataLabel                   matlab.ui.control.Label
        TDFECMElementsLabel            matlab.ui.control.Label
        UseTDFFittingLabel             matlab.ui.control.Label
        EISstarttemperaturCLabel       matlab.ui.control.Label
        EISstartSOCLabel               matlab.ui.control.Label
        EISDRTLabel                    matlab.ui.control.Label
        EISMADtoleranceLabel           matlab.ui.control.Label
        EISpostprocessingLabel         matlab.ui.control.Label
        EISModellLabel                 matlab.ui.control.Label
        UseEISFittingLabel             matlab.ui.control.Label
        ExportInformationTextArea      matlab.ui.control.TextArea
        ExportcellasyamlButton         matlab.ui.control.Button
        CellExportDropDown             matlab.ui.control.DropDown
        CelltoexportLabel              matlab.ui.control.Label
        SpectrumUIAxes                 matlab.ui.control.UIAxes
        ImagEISUIAxes                  matlab.ui.control.UIAxes
        RealEISUIAxes                  matlab.ui.control.UIAxes
        ProgressBarEISUIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %pathes
        working_directory;              % path to the working directory
        path_database;                  % path to the database
        %housings
        vartypes_HousingTable;                   % vartypes of the columns of the electrode table
        units_vars_HousingTable;                 % specifies the units of the columns of the electrode table
        id_class_to_HousingTable;                % map fieldnames of a object of the typ electrode to the column names of the electrode GUI-table
        backup_table_Housing;                    % backup table of the electrode table
        HousingsTable;
        %active material
        vartypes_ACMaterialTable;        % vartypes of the columns of the active material table
        units_vars_ACMaterialTable;      % specifies the units of the columns of the active material table
        id_class_to_ACMaterial_table; 
        ACMaterialTable;
        %blends
        vartypes_BlendsTable;            % vartypes of the columns of the blends table
        units_vars_BlendsTable;          % specifies the units of the columns of the blends table
        id_class_to_Blends_table;        % map fieldnames of a object of the typ blends to the column names of the blends GUI-table
        BlendsTable;
        %electrode stacks
        vartypes_ElectrodeStackTable;            % vartypes of the columns of the electrode stack table
        units_vars_ElectrodeStackTable;          % specifies the units of the columns of the electrode stack table
        id_class_to_ElectrodeStackTable;         % map fieldnames of a object of the typ electrode stack to the column names of the electrode stack GUI-table
        backup_table_ElectrodeStack;             % backup table of the electrode stack table
        %cells
        vartypes_CellsTable;                     % vartypes of the columns of the electrode stack table
        units_vars_CellsTable;                   % specifies the units of the columns of the electrode stack table
        id_class_to_CellsTable;                  % map fieldnames of a object of the typ electrode stack to the column names of the electrode stack GUI-table
        backup_table_Cells;                      % backup table of the electrode stack table
        CellsTableColumnName;                    % Column names for Cell table
        %compare table
        vartypes_CompareTable;                   % vartype of the columns of the compare table
        units_vars_CompareTable;                 % specifies the units of the columns of the compare table
        id_class_to_CompareTable;                % map fieldnames of a object of the compare table to the column names of the compare GUI-table
        %simulation options table
        vartypes_simOptTable;                    % vartype of the columns of the simulation options table
        units_vars_simOptTable;                  % specifies the units of the columns of the simulation options table
        id_class_to_simOptTable;                 % map fieldnames of a object of the simulation options table to the column names of the simulation options GUI-table
        %parameter variation table
        vartypes_paraVarTable;                   % vartype of the columns of the parameter variation table
        units_vars_paraVarTable;                 % specifies the units of the columns of the parameter variation table
        id_para_to_listElement;                  % map fieldnames of a object of the parameter variation table to the row of the parameter variation GUI-table
        %housing variation table
        vartypes_HousingVaraTable;               % vartype of the columns of the housing variation table
        units_vars_HousingVaraTable;             % specifies the units of the columns of the housing variation table
        id_class_to_HousingVaraTable;            % map fieldnames of a object of the housing variation table to the column names of the housing variation GUI-table
        %active material variation table
        vartypes_ACMaterialVaraTable;            % vartype of the columns of the active material variation table
        units_vars_ACMaterialVaraTable;          % specifies the units of the columns of the active material variation table
        id_class_to_ACMaterialVaraTable;    
        %cell variation table
        vartypes_VariedCellsTable;               % vartype of the columns of the varied cells table
        units_vars_VariedCellsTable;             % specifies the units of the columns of the varied cells table
        id_class_to_VariedCellsTable; 
        %parameter variation
        parameters_to_vari                       % specifies parameter to vari
        parameters_to_vari_unit                  % specifies the units of the parameters to vari
        parameters_to_vari_components            % specifies which components can be varied with the parameter 
        parameters_to_vari_Table_VarTypes        % specifies the variable types of the columns of the parameter to vari table
        %sensitivity analysis
        SA_parameter                             % parameter for variation for the sensitivity analysis
        SA_parameter_formular_symbol             % formular symbol for the parameter for variation for the sensitivity analysis
        SA_parameter_unit                        % units for for the parameter for variation for the sensitivity analysis
        SA_Secondary_parameter                   % table for mapping secondary parameter to primary parameter
        %path of the parameter to the attribute in the class
        parameter_path_to_class                  % path to the parameters within the class structure for variation
        %data management
        currentElectrodeStack
        currentHousing
        currentCell
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            clb_initialise_GUI(app);
        end

        % Value changed function: CellNameField
        function CellNameFieldValueChanged(app, event)
            clb_reset_editField_style(event); 
        end

        % Selection changed function: CreationModeButtonMainGroup
        function CreationModeButtonMainGroupSelectionChanged(app, event)
            clb_change_CreationMode_Cell(app,event); % does not exist
        end

        % Callback function
        function PreselectionButtonValueChanged(app, event)
            clb_apply_preselectedData_Main(app);
        end

        % Button pushed function: CalcpropertiesButton
        function CalcpropertiesButtonPushed(app, event)
            clb_calcPropCell_MainTab(app,event);
        end

        % Button pushed function: PlotCellButton
        function PlotCellButtonPushed(app, event)
            clb_plotCell_MainTab(app,event);
        end

        % Button pushed function: LoadDatabaseButton
        function LoadDatabaseButtonPushed(app, event)
            clb_load_database(app);
        end

        % Button pushed function: SavecellButton
        function SavecellButtonPushed(app, event)
            clb_save_Cell_MainTab(app,event);
        end

        % Selection change function: TabGroupMainPanel
        function TabGroupMainPanelSelectionChanged(app, event)
            clb_change_Tab_at_mainTab(app,event);
        end

        % Button pushed function: ComparecellsButton
        function ComparecellsButtonPushed(app, event)
            clb_compareCells_MainTab(app);
        end

        % Button pushed function: SaveFigureCompareButton
        function SaveFigureCompareButtonPushed(app, event)
            clb_save_figures_ComparisonMainTab(app);
        end

        % Button pushed function: SaveFigureCellButton
        function SaveFigureCellButtonPushed(app, event)
            clb_save_figures_MainTab_Cell(app);
        end

        % Value changed function: CellParaVarDropDown
        function CellParaVarDropDownValueChanged(app, event)
            clb_baseCell_paraVar_changed(app);
        end

        % Value changed function: VaryParaListBox
        function VaryParaListBoxValueChanged(app, event)
            clb_change_para_to_vary_selection(app);
        end

        % Button pushed function: RagoneDiagramButton
        function RagoneDiagramButtonPushed(app, event)
            clb_plot_RagoneDiagram(app);
        end

        % Value changed function: ShowvariationsButton
        function ShowvariationsButtonValueChanged(app, event)
            clb_show_variations(app);
        end

        % Close request function: ISEAcellpackdatabaseUIFigure
        function ISEAcellpackdatabaseUIFigureCloseRequest(app, event)
            delete(app)
            clear all;
        end

        % Button pushed function: PlotvariationsButton
        function PlotvariationsButtonPushed(app, event)
            clb_plot_VariedCells(app);
        end

        % Button pushed function: SaveVariationsButton
        function SaveVariationsButtonPushed(app, event)
            clb_save_Variations(app);
        end

        % Cell edit callback: ActiveMaterialParaVarTable, 
        % ...and 1 other component
        function ParaVarTableCellEdit(app, event)
            clb_paraVarTables_edit(app,event);
        end

        % Cell edit callback: VariedCellsTable
        function VariedCellsTableCellEdit(app, event)
            clb_VariedCellsTable_edit(app,event);
        end

        % Button pushed function: ExportcellasyamlButton
        function ExportcellasyamlButtonPushed(app, event)
            clb_exportCell_MainTab(app);
        end

        % Button pushed function: ExporttobatteryIDButton
        function ExporttobatteryIDButtonPushed(app, event)
            clb_exportBatteryID(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ISEAcellpackdatabaseUIFigure = uifigure('Visible', 'off');
            app.ISEAcellpackdatabaseUIFigure.Name = 'MATLAB App';
            app.ISEAcellpackdatabaseUIFigure.CloseRequestFcn = createCallbackFcn(app, @ISEAcellpackdatabaseUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ISEAcellpackdatabaseUIFigure);
            app.AnalysisPanel = uipanel(app.GridLayout);
            app.AnalysisPanel.Title = 'Analysis';
            app.AnalysisLayout = uigridlayout(app.AnalysisPanel);
            app.TabGroupMainPanel = uitabgroup(app.AnalysisLayout);
            app.TabGroupMainPanel.SelectionChangedFcn = createCallbackFcn(app, @TabGroupMainPanelSelectionChanged, true);
            app.ComparisonofdifferentcellsTab = uitab(app.TabGroupMainPanel);
            app.ComparisonofdifferentcellsTab.Title = 'Comparison of different cells';
            app.GridLayoutComparison = uigridlayout(app.ComparisonofdifferentcellsTab);
            app.ComparisonHousingsUIAxes = uiaxes(app.GridLayoutComparison);
            title(app.ComparisonHousingsUIAxes, '3D representation ')
            xlabel(app.ComparisonHousingsUIAxes, 'X [mm]')
            ylabel(app.ComparisonHousingsUIAxes, 'Y [mm]')
            zlabel(app.ComparisonHousingsUIAxes, 'Z [mm]')
            app.ComparisonOCVUIAxes = uiaxes(app.GridLayoutComparison);
            title(app.ComparisonOCVUIAxes, 'Discharge OCVs')
            xlabel(app.ComparisonOCVUIAxes, 'SOC [%]')
            ylabel(app.ComparisonOCVUIAxes, 'Voltage [V]')
            zlabel(app.ComparisonOCVUIAxes, 'Z')
            app.ComparisonSpiderUIAxes = uiaxes(app.GridLayoutComparison);
            title(app.ComparisonSpiderUIAxes, 'Spider web diagram')
            app.ComparecellsButton = uibutton(app.GridLayoutComparison, 'push');
            app.ComparecellsButton.ButtonPushedFcn = createCallbackFcn(app, @ComparecellsButtonPushed, true);
            app.ComparecellsButton.Enable = 'off';
            app.ComparecellsButton.Text = 'Compare cells';
            app.OwnfigureCompareCheckBox = uicheckbox(app.GridLayoutComparison);
            app.OwnfigureCompareCheckBox.Text = 'Own figure';
            app.SaveFigureCompareButton = uibutton(app.GridLayoutComparison, 'push');
            app.SaveFigureCompareButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureCompareButtonPushed, true);
            app.SaveFigureCompareButton.Enable = 'off';
            app.SaveFigureCompareButton.Text = 'Save figures';
            app.SwitchOCVMain = uiswitch(app.GridLayoutComparison, 'slider');
            app.SwitchOCVMain.Items = {'SOC', 'Capacity'};
            app.SwitchOCVMain.Value = 'SOC';
            app.ComparisonTable = uitable(app.GridLayoutComparison);
            app.ComparisonTable.ColumnName = {'Compare'; 'Type'; 'Energy'; 'Power'};
            app.ComparisonTable.RowName = {};
            app.ComparisonTable.ColumnSortable = true;
            app.ComparisonTable.ColumnEditable = [true false false false];
            app.RagoneDiagramButton = uibutton(app.GridLayoutComparison, 'push');
            app.RagoneDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @RagoneDiagramButtonPushed, true);
            app.RagoneDiagramButton.Text = 'Ragone Diagram';
            app.ParametervariationTab = uitab(app.TabGroupMainPanel);
            app.ParametervariationTab.Title = 'Parameter variation';
            app.ParaVariationGridLayout = uigridlayout(app.ParametervariationTab);
            app.SelectbasecellLabel = uilabel(app.ParaVariationGridLayout);
            app.SelectbasecellLabel.Text = 'Select base cell:';
            app.CellParaVarDropDown = uidropdown(app.ParaVariationGridLayout);
            app.CellParaVarDropDown.Items = {};
            app.CellParaVarDropDown.ValueChangedFcn = createCallbackFcn(app, @CellParaVarDropDownValueChanged, true);
            app.CellParaVarDropDown.Value = {};
            app.ParametervariationPanel = uipanel(app.ParaVariationGridLayout);
            app.ParametervariationPanel.Title = 'Parameter variation';
            app.ParaVarTableGridLayout = uigridlayout(app.ParametervariationPanel);
            app.ParaVarTable = uitable(app.ParaVarTableGridLayout);
            app.ParaVarTable.ColumnName = {'Vary'; 'Init value'; 'min'; 'max'; '# variations'};
            app.ParaVarTable.RowName = {};
            app.ParaVarTable.ColumnEditable = [true false true true true];
            app.ParaVarTable.Visible = 'off';
            app.VariedCellsTable = uitable(app.ParaVarTableGridLayout);
            app.VariedCellsTable.ColumnName = {'Name'; 'Select'; 'Housing'; 'Active material anode'; 'Active material cathode'; 'Energy'; 'Power'; 'Nominal voltage'; 'Gravimetric energy density'; 'Gravimetric power density'; 'Internal resistance'};
            app.VariedCellsTable.RowName = {};
            app.VariedCellsTable.ColumnSortable = [true true true true false true true true true true true];
            app.VariedCellsTable.ColumnEditable = [true true false false false false false false false false false];
            app.VariedCellsTable.CellEditCallback = createCallbackFcn(app, @VariedCellsTableCellEdit, true);
            app.VariedCellsTable.Visible = 'off';
            app.ComponentvariationPanel = uipanel(app.ParaVariationGridLayout);
            app.ComponentvariationPanel.Title = 'Component variation';
            app.ComponentVariationGridLayout = uigridlayout(app.ComponentvariationPanel);
            app.HousingPanel = uipanel(app.ComponentVariationGridLayout);
            app.HousingPanel.Title = 'Housing';
            app.HousingPanel.Visible = 'off';
            app.HousingTableGridLayout = uigridlayout(app.HousingPanel);
            app.HousingParaVarTable = uitable(app.HousingTableGridLayout);
            app.HousingParaVarTable.ColumnName = {'Vary'; 'Type'; 'Inner volume'; 'Weight'};
            app.HousingParaVarTable.RowName = {};
            app.HousingParaVarTable.ColumnEditable = [true false false false];
            app.HousingParaVarTable.CellEditCallback = createCallbackFcn(app, @ParaVarTableCellEdit, true);
            app.HousingParaVarTable.Visible = 'off';
            app.HousingParaVarTable.Tag = 'HousingPanel';
            app.ActivematerialPanel = uipanel(app.ComponentVariationGridLayout);
            app.ActivematerialPanel.Title = 'Active material';
            app.ActivematerialPanel.Visible = 'off';
            app.ACMTableGridLayout = uigridlayout(app.ActivematerialPanel);
            app.ActiveMaterialParaVarTable = uitable(app.ACMTableGridLayout);
            app.ActiveMaterialParaVarTable.ColumnName = {'Is Anode'; 'Is Cathode'; 'Formular'; 'Grav. Capacity'; 'Max. OCP'; 'Min. OCP'};
            app.ActiveMaterialParaVarTable.RowName = {};
            app.ActiveMaterialParaVarTable.ColumnSortable = [false false true true true true];
            app.ActiveMaterialParaVarTable.ColumnEditable = [true true false false false false];
            app.ActiveMaterialParaVarTable.CellEditCallback = createCallbackFcn(app, @ParaVarTableCellEdit, true);
            app.ActiveMaterialParaVarTable.Visible = 'off';
            app.ActiveMaterialParaVarTable.Tag = 'ActivematerialPanel';
            app.CapacityParaVarLabel = uilabel(app.ParaVariationGridLayout);
            app.CapacityParaVarLabel.Text = 'Capacity: 0 Ah';
            app.PowerParaVarLabel = uilabel(app.ParaVariationGridLayout);
            app.PowerParaVarLabel.Text = 'Power: 0 W';
            app.ChooseparameterorcomponentstovaryListBoxLabel = uilabel(app.ParaVariationGridLayout);
            app.ChooseparameterorcomponentstovaryListBoxLabel.Text = 'Choose parameter or components to vary:';
            app.VaryParaListBox = uilistbox(app.ParaVariationGridLayout);
            app.VaryParaListBox.Items = {};
            app.VaryParaListBox.Multiselect = 'on';
            app.VaryParaListBox.ValueChangedFcn = createCallbackFcn(app, @VaryParaListBoxValueChanged, true);
            app.VaryParaListBox.Value = {};
            app.NVariationsLabel = uilabel(app.ParaVariationGridLayout);
            app.NVariationsLabel.Text = 'Number of variations: 0';
            app.PlotvariationsButton = uibutton(app.ParaVariationGridLayout, 'push');
            app.PlotvariationsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotvariationsButtonPushed, true);
            app.PlotvariationsButton.Enable = 'off';
            app.PlotvariationsButton.Text = 'Plot variations';
            app.ShowvariationsButton = uibutton(app.ParaVariationGridLayout, 'state');
            app.ShowvariationsButton.ValueChangedFcn = createCallbackFcn(app, @ShowvariationsButtonValueChanged, true);
            app.ShowvariationsButton.Enable = 'off';
            app.ShowvariationsButton.Text = 'Show variations';
            app.SaveVariationsButton = uibutton(app.ParaVariationGridLayout, 'push');
            app.SaveVariationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVariationsButtonPushed, true);
            app.SaveVariationsButton.Enable = 'off';
            app.SaveVariationsButton.Text = 'Save variations';
            app.FitStackHousingCheckBox = uicheckbox(app.ParaVariationGridLayout);
            app.FitStackHousingCheckBox.Text = 'Fit stack to housing';
            app.HoldSurfaceCapacityCheckBox = uicheckbox(app.ParaVariationGridLayout);
            app.HoldSurfaceCapacityCheckBox.Text = 'Hold surface capacity';
            app.ParaVariSurfaceCapacityField = uieditfield(app.ParaVariationGridLayout, 'numeric');
            app.ParaVariSurfaceCapacityField.Limits = [0 Inf];
            app.InterfacetoPCMTab = uitab(app.TabGroupMainPanel);
            app.InterfacetoPCMTab.Title = 'Interface to PCM';
            app.GridLayoutCellExport = uigridlayout(app.InterfacetoPCMTab);
            app.ProgressBarEISUIAxes = uiaxes(app.GridLayoutCellExport);
            app.ProgressBarEISUIAxes.Visible = 'off';
            app.RealEISUIAxes = uiaxes(app.GridLayoutCellExport);
            title(app.RealEISUIAxes, 'Title')
            xlabel(app.RealEISUIAxes, 'X')
            ylabel(app.RealEISUIAxes, 'Y')
            zlabel(app.RealEISUIAxes, 'Z')
            app.RealEISUIAxes.Visible = 'off';
            app.ImagEISUIAxes = uiaxes(app.GridLayoutCellExport);
            title(app.ImagEISUIAxes, 'Title')
            xlabel(app.ImagEISUIAxes, 'X')
            ylabel(app.ImagEISUIAxes, 'Y')
            zlabel(app.ImagEISUIAxes, 'Z')
            app.ImagEISUIAxes.Visible = 'off';
            app.SpectrumUIAxes = uiaxes(app.GridLayoutCellExport);
            title(app.SpectrumUIAxes, 'Title')
            xlabel(app.SpectrumUIAxes, 'X')
            ylabel(app.SpectrumUIAxes, 'Y')
            zlabel(app.SpectrumUIAxes, 'Z')
            app.SpectrumUIAxes.Visible = 'off';
            app.CelltoexportLabel = uilabel(app.GridLayoutCellExport);
            app.CelltoexportLabel.Text = 'Cell to export:';
            app.CellExportDropDown = uidropdown(app.GridLayoutCellExport);
            app.CellExportDropDown.Items = {};
            app.CellExportDropDown.Value = {};
            app.ExportcellasyamlButton = uibutton(app.GridLayoutCellExport, 'push');
            app.ExportcellasyamlButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcellasyamlButtonPushed, true);
            app.ExportcellasyamlButton.Text = 'Export cell as yaml';
            app.ExportInformationTextArea = uitextarea(app.GridLayoutCellExport);
            app.ExportInformationTextArea.Value = {'Export information:'};
            app.FittingOptionsPanel = uipanel(app.GridLayoutCellExport);
            app.FittingOptionsPanel.Title = 'Fitting options:';
            app.FittingOptionsPanel.Visible = 'off';
            app.FittingOptionsGridLayout = uigridlayout(app.FittingOptionsPanel);
            app.UseEISFittingLabel = uilabel(app.FittingOptionsGridLayout);
            app.UseEISFittingLabel.Text = 'Use EIS-Fitting:';
            app.EISModellLabel = uilabel(app.FittingOptionsGridLayout);
            app.EISModellLabel.Text = 'EIS-Modell:';
            app.EISpostprocessingLabel = uilabel(app.FittingOptionsGridLayout);
            app.EISpostprocessingLabel.Text = 'EIS post processing:';
            app.EISMADtoleranceLabel = uilabel(app.FittingOptionsGridLayout);
            app.EISMADtoleranceLabel.Text = 'EIS MAD tolerance:';
            app.EISDRTLabel = uilabel(app.FittingOptionsGridLayout);
            app.EISDRTLabel.Text = 'EIS DRT:';
            app.EISstartSOCLabel = uilabel(app.FittingOptionsGridLayout);
            app.EISstartSOCLabel.Text = 'EIS start SOC [%]';
            app.EISstarttemperaturCLabel = uilabel(app.FittingOptionsGridLayout);
            app.EISstarttemperaturCLabel.Text = 'EIS start temperatur [°C]:';
            app.UseTDFFittingLabel = uilabel(app.FittingOptionsGridLayout);
            app.UseTDFFittingLabel.Text = 'Use TDF-Fitting:';
            app.TDFECMElementsLabel = uilabel(app.FittingOptionsGridLayout);
            app.TDFECMElementsLabel.Text = 'TDF ECM-Elements:';
            app.TDFDataLabel = uilabel(app.FittingOptionsGridLayout);
            app.TDFDataLabel.Text = 'TDF Data:';
            app.VerificationLabel = uilabel(app.FittingOptionsGridLayout);
            app.VerificationLabel.Text = 'Verification:';
            app.UseEISFittingButtonGroup = uibuttongroup(app.FittingOptionsGridLayout);
            app.EISFittingYesButton = uiradiobutton(app.UseEISFittingButtonGroup);
            app.EISFittingYesButton.Text = 'Yes';
            app.EISFittingYesButton.Value = true;
            app.EISFittingNoButton = uiradiobutton(app.UseEISFittingButtonGroup);
            app.EISFittingNoButton.Text = 'No';
            app.EISModellDropDown = uidropdown(app.FittingOptionsGridLayout);
            app.EISModellDropDown.Items = {'R2RC'};
            app.EISModellDropDown.Value = 'R2RC';
            app.UseEISPostProcessingButtonGroup = uibuttongroup(app.FittingOptionsGridLayout);
            app.EISPostProcessingYesButton = uiradiobutton(app.UseEISPostProcessingButtonGroup);
            app.EISPostProcessingYesButton.Text = 'Yes';
            app.EISPostProcessingNoButton = uiradiobutton(app.UseEISPostProcessingButtonGroup);
            app.EISPostProcessingNoButton.Text = 'No';
            app.EISPostProcessingNoButton.Value = true;
            app.EISMadTolceranceField = uieditfield(app.FittingOptionsGridLayout, 'numeric');
            app.EISMadTolceranceField.Value = 3;
            app.UseEISDRTButtonGroup = uibuttongroup(app.FittingOptionsGridLayout);
            app.EISDRTYesButton = uiradiobutton(app.UseEISDRTButtonGroup);
            app.EISDRTYesButton.Text = 'Yes';
            app.EISDRTNoButton = uiradiobutton(app.UseEISDRTButtonGroup);
            app.EISDRTNoButton.Text = 'No';
            app.EISDRTNoButton.Value = true;
            app.EISStartSOCField = uieditfield(app.FittingOptionsGridLayout, 'numeric');
            app.EISStartSOCField.Limits = [0 Inf];
            app.EISStartSOCField.Value = 50;
            app.EISStartTemperaturField = uieditfield(app.FittingOptionsGridLayout, 'numeric');
            app.EISStartTemperaturField.Value = 25;
            app.UseTDFFittingButtonGroup = uibuttongroup(app.FittingOptionsGridLayout);
            app.EISTDFYesButton = uiradiobutton(app.UseTDFFittingButtonGroup);
            app.EISTDFYesButton.Text = 'Yes';
            app.EISTDFYesButton.Value = true;
            app.EISTDFNoButton = uiradiobutton(app.UseTDFFittingButtonGroup);
            app.EISTDFNoButton.Text = 'No';
            app.TDFECDElementsField = uieditfield(app.FittingOptionsGridLayout, 'text');
            app.TDFECDElementsField.Value = 'e.g. 2,3';
            app.TDFDataDropDown = uidropdown(app.FittingOptionsGridLayout);
            app.TDFDataDropDown.Items = {'Pulse'};
            app.TDFDataDropDown.Value = 'Pulse';
            app.UseVerificationButtonGroup = uibuttongroup(app.FittingOptionsGridLayout);
            app.VerficationYesButton = uiradiobutton(app.UseVerificationButtonGroup);
            app.VerficationYesButton.Text = 'Yes';
            app.VerficationYesButton.Value = true;
            app.VerificationNoButton = uiradiobutton(app.UseVerificationButtonGroup);
            app.VerificationNoButton.Text = 'No';
            app.SamplerateLabel = uilabel(app.FittingOptionsGridLayout);
            app.SamplerateLabel.Text = 'Sample rate';
            app.VerificationSampleRateField = uieditfield(app.FittingOptionsGridLayout, 'numeric');
            app.VerificationSampleRateField.Limits = [0 Inf];
            app.VerificationSampleRateField.Value = 1;
            app.SimulationOptionsPanel = uipanel(app.GridLayoutCellExport);
            app.SimulationOptionsPanel.Title = 'Simulation Options';
            app.SimulationOptionsGridLayout = uigridlayout(app.SimulationOptionsPanel);
            app.AmbienttemperatureLabel = uilabel(app.SimulationOptionsGridLayout);
            app.AmbienttemperatureLabel.Text = 'Ambient temperature:';
            app.CelltemperatureLabel = uilabel(app.SimulationOptionsGridLayout);
            app.CelltemperatureLabel.Text = 'Cell temperature:';
            app.StateofchargeLabel = uilabel(app.SimulationOptionsGridLayout);
            app.StateofchargeLabel.Text = 'State of charge:';
            app.ParticletypeLabel = uilabel(app.SimulationOptionsGridLayout);
            app.ParticletypeLabel.Text = 'Particle type:';
            app.ParticledivisonLabel = uilabel(app.SimulationOptionsGridLayout);
            app.ParticledivisonLabel.Text = 'Particle divison:';
            app.DivisionLabel = uilabel(app.SimulationOptionsGridLayout);
            app.DivisionLabel.Text = 'Division:';
            app.AmbientTempField = uieditfield(app.SimulationOptionsGridLayout, 'numeric');
            app.AmbientTempField.Value = 25;
            app.CellTempField = uieditfield(app.SimulationOptionsGridLayout, 'numeric');
            app.CellTempField.Value = 25;
            app.SOCField = uieditfield(app.SimulationOptionsGridLayout, 'numeric');
            app.SOCField.Limits = [0 Inf];
            app.SOCField.Value = 50;
            app.ParticleTypeDropDown = uidropdown(app.SimulationOptionsGridLayout);
            app.ParticleTypeDropDown.Items = {'spherical', 'hystRelax', 'planar', 'plating', 'sphericalHyst', 'spherical polynominal'};
            app.ParticleTypeDropDown.Value = 'spherical';
            app.ParticleDivisionField = uieditfield(app.SimulationOptionsGridLayout, 'numeric');
            app.ParticleDivisionField.Limits = [0 Inf];
            app.ParticleDivisionField.Value = 10;
            app.DivisionField = uieditfield(app.SimulationOptionsGridLayout, 'numeric');
            app.DivisionField.Limits = [0 Inf];
            app.DivisionField.Value = 10;
            app.ExporttobatteryIDButton = uibutton(app.GridLayoutCellExport, 'push');
            app.ExporttobatteryIDButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttobatteryIDButtonPushed, true);
            app.ExporttobatteryIDButton.Text = 'Export to battery.ID';
            app.ExportbatteryIDasPDFCheckBox = uicheckbox(app.GridLayoutCellExport);
            app.ExportbatteryIDasPDFCheckBox.Text = 'Export battery.ID as PDF';
            app.DynamicCellInformationPanel = uipanel(app.GridLayout);
            app.DynamicCellInformationPanel.Title = 'Graphical representation of the cell';
            app.InformationCellGridLayout = uigridlayout(app.DynamicCellInformationPanel);
            app.OCVMainUIAxes = uiaxes(app.InformationCellGridLayout);
            title(app.OCVMainUIAxes, 'Open circuit voltage')
            xlabel(app.OCVMainUIAxes, 'SOC [%]')
            ylabel(app.OCVMainUIAxes, 'Voltage [V]')
            zlabel(app.OCVMainUIAxes, 'Z')
            app.HousingMainUIAxes = uiaxes(app.InformationCellGridLayout);
            title(app.HousingMainUIAxes, '3D representation')
            xlabel(app.HousingMainUIAxes, 'X [mm]')
            ylabel(app.HousingMainUIAxes, 'Y [mm]')
            zlabel(app.HousingMainUIAxes, 'Z [mm]')
            app.CapacityLabel = uilabel(app.InformationCellGridLayout);
            app.CapacityLabel.Visible = 'off';
            app.CapacityLabel.Text = 'Capacity: 0 Ah';
            app.PowerLabel = uilabel(app.InformationCellGridLayout);
            app.PowerLabel.Visible = 'off';
            app.PowerLabel.Text = 'Power: 0 W';
            app.VolumeLabel = uilabel(app.InformationCellGridLayout);
            app.VolumeLabel.Visible = 'off';
            app.VolumeLabel.Text = 'Volume: 0 cm³';
            app.WeightLabel = uilabel(app.InformationCellGridLayout);
            app.WeightLabel.Visible = 'off';
            app.WeightLabel.Text = 'Weight: 0 g';
            app.TypeCell = uilabel(app.InformationCellGridLayout);
            app.TypeCell.Visible = 'off';
            app.TypeCell.Text = 'Type of the cell:';
            app.InternalResistanceLabel = uilabel(app.InformationCellGridLayout);
            app.InternalResistanceLabel.Visible = 'off';
            app.InternalResistanceLabel.Text = 'Internal resistance: 0 mΩ';
            app.GravEnergyDensityLabel = uilabel(app.InformationCellGridLayout);
            app.GravEnergyDensityLabel.Visible = 'off';
            app.GravEnergyDensityLabel.Text = 'Gravimetric energy density: 0 Wh/kg';
            app.VolEnergyDensityLabel = uilabel(app.InformationCellGridLayout);
            app.VolEnergyDensityLabel.Visible = 'off';
            app.VolEnergyDensityLabel.Text = 'Volumetric energy density: 0 Wh/l';
            app.GravPowerDensityLabel = uilabel(app.InformationCellGridLayout);
            app.GravPowerDensityLabel.Visible = 'off';
            app.GravPowerDensityLabel.Text = 'Gravimetric power density: 0 W/kg';
            app.VolPowerDensityLabel = uilabel(app.InformationCellGridLayout);
            app.VolPowerDensityLabel.Visible = 'off';
            app.VolPowerDensityLabel.Text = 'Volumetric power density: 0 W/l';
            app.NomDisVoltageLabel = uilabel(app.InformationCellGridLayout);
            app.NomDisVoltageLabel.Visible = 'off';
            app.NomDisVoltageLabel.Text = 'Nominal discharge voltage: 0 V';
            app.NomChaVoltageLabel = uilabel(app.InformationCellGridLayout);
            app.NomChaVoltageLabel.Visible = 'off';
            app.NomChaVoltageLabel.Text = 'Nominal charge voltage: 0 V';
            app.SwitchOCVMainCell = uiswitch(app.InformationCellGridLayout, 'slider');
            app.SwitchOCVMainCell.Items = {'SOC', 'Capacity'};
            app.SwitchOCVMainCell.Value = 'SOC';
            app.SaveFigureCellButton = uibutton(app.InformationCellGridLayout, 'push');
            app.SaveFigureCellButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureCellButtonPushed, true);
            app.SaveFigureCellButton.Enable = 'off';
            app.SaveFigureCellButton.Text = 'Save figures';
            app.CellBuilding = uipanel(app.GridLayout);
            app.CellBuilding.Title = 'Design your own cell fast:';
            app.GridLayoutCellBuilding = uigridlayout(app.CellBuilding);
            app.SavecellButton = uibutton(app.GridLayoutCellBuilding, 'push');
            app.SavecellButton.ButtonPushedFcn = createCallbackFcn(app, @SavecellButtonPushed, true);
            app.SavecellButton.Tag = 'Main';
            app.SavecellButton.Enable = 'off';
            app.SavecellButton.Text = 'Save cell';
            app.CalcpropertiesButton = uibutton(app.GridLayoutCellBuilding, 'push');
            app.CalcpropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @CalcpropertiesButtonPushed, true);
            app.CalcpropertiesButton.Tag = 'Main';
            app.CalcpropertiesButton.Enable = 'off';
            app.CalcpropertiesButton.Text = 'Calc properties';
            app.PlotCellButton = uibutton(app.GridLayoutCellBuilding, 'push');
            app.PlotCellButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCellButtonPushed, true);
            app.PlotCellButton.Tag = 'Main';
            app.PlotCellButton.Enable = 'off';
            app.PlotCellButton.Text = 'Plot cell';
            app.DropDownConfidential = uidropdown(app.GridLayoutCellBuilding);
            app.DropDownConfidential.Items = {'Yes', 'No'};
            app.DropDownConfidential.Value = 'No';
            app.IssourceconfidentialLabel = uilabel(app.GridLayoutCellBuilding);
            app.IssourceconfidentialLabel.Text = 'Is source confidential?';
            app.sourceField = uieditfield(app.GridLayoutCellBuilding, 'text');
            app.sourceField.Value = 'source';
            app.SourceofthedataLabel = uilabel(app.GridLayoutCellBuilding);
            app.SourceofthedataLabel.Text = 'Source of the data';
            app.CreationModeButtonMainGroup = uibuttongroup(app.GridLayoutCellBuilding);
            app.CreationModeButtonMainGroup.SelectionChangedFcn = createCallbackFcn(app, @CreationModeButtonMainGroupSelectionChanged, true);
            app.CreationModeButtonMainGroup.Tag = 'Main';
            app.standardMainButton = uiradiobutton(app.CreationModeButtonMainGroup);
            app.standardMainButton.Text = 'standard';
            app.standardMainButton.Value = true;
            app.fitHousingMainButton = uiradiobutton(app.CreationModeButtonMainGroup);
            app.fitHousingMainButton.Text = 'fit stack to housing';
            app.fitCapacityMainButton = uiradiobutton(app.CreationModeButtonMainGroup);
            app.fitCapacityMainButton.Text = 'fit cell to capacity';
            app.CreationModeLabel = uilabel(app.GridLayoutCellBuilding);
            app.CreationModeLabel.Text = 'Creation mode of the new cell:';
            app.HousingMainDropDown = uidropdown(app.GridLayoutCellBuilding);
            app.HousingMainDropDown.Items = {};
            app.HousingMainDropDown.Value = {};
            app.HousingLabel = uilabel(app.GridLayoutCellBuilding);
            app.HousingLabel.Text = 'Housing of the new cell:';
            app.ElectrodeStackMainDropDown = uidropdown(app.GridLayoutCellBuilding);
            app.ElectrodeStackMainDropDown.Items = {};
            app.ElectrodeStackMainDropDown.Value = {};
            app.ElectrodestackLabel = uilabel(app.GridLayoutCellBuilding);
            app.ElectrodestackLabel.Text = 'Electrode stack of the new cell:';
            app.CellNameField = uieditfield(app.GridLayoutCellBuilding, 'text');
            app.CellNameField.ValueChangedFcn = createCallbackFcn(app, @CellNameFieldValueChanged, true);
            app.NameofthenewcellLabel = uilabel(app.GridLayoutCellBuilding);
            app.NameofthenewcellLabel.Text = 'Name of the new cell:';
            app.BusyMainLamp = uilamp(app.GridLayoutCellBuilding);
            app.BusyLampLabel = uilabel(app.GridLayoutCellBuilding);
            app.BusyLampLabel.Text = 'Busy';
            app.OwnfigureCheckBox = uicheckbox(app.GridLayoutCellBuilding);
            app.OwnfigureCheckBox.Text = 'Own figure';
            app.LoadDatabaseButton = uibutton(app.GridLayoutCellBuilding, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseButtonPushed, true);
            app.LoadDatabaseButton.Text = 'Load Database';
            app.EditFieldDatabasePath = uieditfield(app.GridLayoutCellBuilding, 'text');
            app.TargetCapacityMainField = uieditfield(app.GridLayoutCellBuilding, 'numeric');
            app.TargetCapacityMainField.Limits = [0 Inf];
            app.TargetCapacityMainField.Enable = 'off';
            app.TargetCapacityMainLabel = uilabel(app.GridLayoutCellBuilding);
            app.TargetCapacityMainLabel.Enable = 'off';
            app.TargetCapacityMainLabel.Text = 'Target capacity in Ah';
            app.CARL_ISEA_Logo = uiimage(app.GridLayout);
            app.CARL_ISEA_Logo.ImageSource = fullfile(pathToMLAPP, 'src', 'Documentation', 'GUI_Images', 'CARL_ISEA_RWTH.svg');
            app.CARL_ISEA_Logo_2 = uiimage(app.GridLayout);
            app.CARL_ISEA_Logo_2.ImageSource = fullfile(pathToMLAPP, 'src', 'Documentation', 'GUI_Images', 'ICPD_Logo.svg');
            % Show the figure after all components are created
            app.ISEAcellpackdatabaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICPD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ISEAcellpackdatabaseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISEAcellpackdatabaseUIFigure)
        end
    end
end